classdef Inverted_pendulum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        StartButton             matlab.ui.control.Button
        PositionEditFieldLabel  matlab.ui.control.Label
        Position                matlab.ui.control.NumericEditField
        AngleLabel              matlab.ui.control.Label
        Angle                   matlab.ui.control.NumericEditField
        INPROGRESSLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        cartpole = struct
    end
    
    methods (Access = private)
        
        function disp_cartpole(app, state)
            
            x = state(1);
            theta = state(3);
            
            al = app.cartpole.pendulum.arm_length;
            mr = app.cartpole.pendulum.mass_radius;
            cw = app.cartpole.cart.cart_width; 
            ch = app.cartpole.cart.cart_height; 
            wr = app.cartpole.cart.wheel_radius; 
            
            y = wr/2+ch/2;
            
            cartx1 = x-cw/2;
            cartx2 = y-ch/2;
            carty1 = cw;
            carty2 = ch;
            cla(app.UIAxes)
            hold(app.UIAxes,'on');
            plot(app.UIAxes,[-10 10],[-0.01 -0.01],'k','LineWidth',1),
            rectangle(app.UIAxes,'Position',[cartx1, cartx2, carty1, carty2],'Curvature',0.2,'EdgeColor', 'none', 'FaceColor',[0.4 0.5 1]);
            rectangle(app.UIAxes,'Position',[x-.9*cw/2,0,wr,wr],'Curvature',1,'FaceColor',[0 0 0],'LineWidth',1.5); 
            rectangle(app.UIAxes,'Position',[x+.9*cw/2-wr,0,wr,wr],'Curvature',1,'FaceColor',[0 0 0],'LineWidth',1.5); 
            plot(app.UIAxes,[x x+al*sin(theta)],[wr/2+ch/2 wr/2+ch/2 - al*cos(theta)],'k','LineWidth',5, 'Color',[0.72 0.45 0.33]); 
            rectangle(app.UIAxes,'Position',[x+al*sin(theta)-mr/2,y - al*cos(theta)-mr/2,mr,mr],'Curvature',1,'FaceColor',[0.75 0.75 0.75], 'EdgeColor', [0.75 0.75 0.75]);
    
            app.UIAxes.YLim = [-5 5];
            app.UIAxes.YLim = [-2.5 6.5];
            axis(app.UIAxes, 'equal');
            hold(app.UIAxes,'off');
            pause(0.01)
        end
        
        function state_dot = cartpole_dyn(app, state, command)
            cart = app.cartpole;
            g = cart.g;
            L = cart.pendulum.arm_length;
            m = cart.pendulum.mass;
            M = cart.cart.mass;
            d = cart.cart.damping;
            
            sx = sin(state(3));
            cx = cos(state(3));
            D = m*L*L*(M+m*(1-cx^2));
            
            state_dot(1,1) = state(2);
            state_dot(2,1) = (1/D)*(m^2*L^2*g*cx*sx + m*L^2*(m*L*state(4)^2*sx - d*state(2))) + m*L*L*(1/D)*command;
            state_dot(3,1) = state(4);
            state_dot(4,1) = (1/D)*(-(m+M)*m*g*L*sx - m*L*cx*(m*L*state(4)^2*sx - d*state(2))) - m*L*cx*(1/D)*command;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           disableDefaultInteractivity(app.UIAxes)
            
           state0 = [0; 0; pi; .5];
           
           % Create cartpole structure (TO DO info from figure)
           app.cartpole.pendulum.arm_length = 2.5;
           app.cartpole.pendulum.mass_radius = 0.3;
           app.cartpole.pendulum.mass = 1;
           app.cartpole.cart.mass = 5;
           app.cartpole.cart.cart_width = 2; 
           app.cartpole.cart.cart_height = 1; 
           app.cartpole.cart.wheel_radius = 0.5;
           app.cartpole.cart.damping = 1;
           app.cartpole.g = 9.81;
           
           % Display cartpole
           disp_cartpole(app, state0)
           

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable = 'off';
            tspan = 0:0.1:10;
            x0 = app.Position.Value;
            if isempty(x0)
                x0 = 0;
                app.Position.Value = 0;
            end
            
            theta0 = app.Angle.Value;
            if isempty(theta0)
                theta0 = 0;
                app.Angle.Value = 3.14;
            end
            
            y0 = [x0; 0; theta0; 0.5];
            
            [t,y] = ode45(@(t,y) cartpole_dyn(app,y,0),tspan,y0);

            for k=1:length(t)
                disp_cartpole(app, y(k,:))
            end
            app.StartButton.Enable = 'on';
        end

        % Value changed function: Position
        function PositionValueChanged(app, event)
            value = app.Position.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Inverted Pendulum Animation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-5 5];
            app.UIAxes.YLim = [-3 5];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PositionEditFieldLabel = uilabel(app.UIFigure);
            app.PositionEditFieldLabel.Text = 'Position';
            app.Position = uieditfield(app.UIFigure, 'numeric');
            app.Position.ValueChangedFcn = createCallbackFcn(app, @PositionValueChanged, true);
            app.AngleLabel = uilabel(app.UIFigure);
            app.AngleLabel.Text = 'Angle';
            app.Angle = uieditfield(app.UIFigure, 'numeric');
            app.Angle.Value = 3.14;
            app.INPROGRESSLabel = uilabel(app.UIFigure);
            app.INPROGRESSLabel.Text = 'IN PROGRESS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inverted_pendulum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end