classdef Pole_placement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        uipanel2                        matlab.ui.container.Panel
        SystemorderDropDownLabel        matlab.ui.control.Label
        SystemorderDropDown             matlab.ui.control.DropDown
        ParametersPanel_2               matlab.ui.container.Panel
        KEditField_2Label               matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        T1EditFieldLabel                matlab.ui.control.Label
        T1EditField                     matlab.ui.control.NumericEditField
        T2EditFieldLabel                matlab.ui.control.Label
        T2EditField                     matlab.ui.control.NumericEditField
        panel_4                         matlab.ui.container.Panel
        text_k_10                       matlab.ui.control.Label
        text_k_11                       matlab.ui.control.Label
        uipanel4_4                      matlab.ui.container.Panel
        uipanel5_4                      matlab.ui.container.Panel
        text_k_12                       matlab.ui.control.Label
        panel_8                         matlab.ui.container.Panel
        text_k_20                       matlab.ui.control.Label
        text_k_21                       matlab.ui.control.Label
        uipanel4_7                      matlab.ui.container.Panel
        uipanel5_7                      matlab.ui.container.Panel
        text_k_22                       matlab.ui.control.Label
        ControllerPanel                 matlab.ui.container.Panel
        ControllerDropDownLabel         matlab.ui.control.Label
        ControllerDropDown              matlab.ui.control.DropDown
        ParametersPanel                 matlab.ui.container.Panel
        CEditFieldLabel                 matlab.ui.control.Label
        CEditField                      matlab.ui.control.NumericEditField
        TiEditFieldLabel                matlab.ui.control.Label
        TiEditField                     matlab.ui.control.NumericEditField
        TdEditFieldLabel                matlab.ui.control.Label
        TdEditField                     matlab.ui.control.NumericEditField
        panel_5                         matlab.ui.container.Panel
        uipanel4_5                      matlab.ui.container.Panel
        text_k_13                       matlab.ui.control.Label
        uipanel5_5                      matlab.ui.container.Panel
        text_k_15                       matlab.ui.control.Label
        text_k_14                       matlab.ui.control.Label
        panel_7                         matlab.ui.container.Panel
        text_k_19                       matlab.ui.control.Label
        panel_6                         matlab.ui.container.Panel
        uipanel4_6                      matlab.ui.container.Panel
        text_k_16                       matlab.ui.control.Label
        uipanel5_6                      matlab.ui.container.Panel
        text_k_18                       matlab.ui.control.Label
        text_k_17                       matlab.ui.control.Label
        DesiredPerformancesPanel        matlab.ui.container.Panel
        TimecriteriaDropDownLabel       matlab.ui.control.Label
        TimecriteriaDropDown            matlab.ui.control.DropDown
        StaticgainEditFieldLabel        matlab.ui.control.Label
        StaticgainEditField             matlab.ui.control.NumericEditField
        OvershootEditFieldLabel         matlab.ui.control.Label
        OvershootEditField              matlab.ui.control.NumericEditField
        sEditFieldLabel                 matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.NumericEditField
        CheckBox                        matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        ObtainedPerformancesPanel       matlab.ui.container.Panel
        text25                          matlab.ui.control.Label
        text26                          matlab.ui.control.Label
        NotfinishedworkinprogressLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        system % Description
        controller % Description
    end
    
    methods (Access = private)
        
        function [K, T1, T2] = get_system_data(app)
            K = app.system.K;
            T1 = app.system.T1;
            T2 = app.system.T2;
        end
        
        function [Kdes, Tdes, Ddes] = get_desired(app)
            Kdes = app.StaticgainEditField.Value;
            Tdes = app.TimeEditField.Value;
            Ddes = app.OvershootEditField.Value;
        end
        
        function update_system(app)
            K = app.KEditField.Value;
            T1 = app.T1EditField.Value;
            T2 = app.T2EditField.Value;
            
            app.system.K = K;
            app.system.T1 = T1;
            if strcmp(app.SystemorderDropDown.Value, '1st')
                app.system.T2 = 0;
            else
                app.system.T2 = T2;
            end
            app.system.sys = tf(K,[T1 1])*tf(1,[T2 1]);
        end
        
        function update_controller_field(app, C, Ti, Td)
            app.CEditField.Value = C;
            app.TiEditField.Value = Ti;
            app.TdEditField.Value = Td;
        end
        
        function update_controller(app)
            C = app.CEditField.Value;
            Ti = app.TiEditField.Value;
            Td = app.TdEditField.Value;

            app.controller.C = C;
            app.controller.Ti = Ti;
            app.controller.Td = Td;

            if strcmp(app.ControllerDropDown.Value, 'P')
                ctr = C;
            elseif strcmp(app.ControllerDropDown.Value, 'PI')
                ctr = C*tf([Ti 1], [Ti 0]);
            else
                ctr = C*tf([Ti*Td Ti+Td 1], [Ti 0]);
            end
            app.controller.ctr = ctr;
        end
        
        function update_ui(app)
            if strcmp(app.ControllerDropDown.Value, 'P')
                app.OvershootEditField.Visible = 'off';
                app.OvershootEditFieldLabel.Visible = 'off';
                app.CheckBox_2.Visible = 'on';
                app.CheckBox.Visible = 'on';
                app.StaticgainEditField.Visible = 'on';
                app.StaticgainEditFieldLabel.Visible = 'on';
                app.panel_7.Visible ='on';
                app.panel_6.Visible ='off';
                app.panel_5.Visible ='off';
            else 
                app.OvershootEditField.Visible = 'on';
                app.OvershootEditFieldLabel.Visible = 'on';
                app.CheckBox_2.Visible = 'off';
                app.CheckBox.Visible = 'off';
                app.StaticgainEditField.Visible = 'off';
                app.StaticgainEditFieldLabel.Visible = 'off';
                if strcmp(app.ControllerDropDown.Value, 'PI')
                    app.panel_7.Visible ='off';
                    app.panel_6.Visible ='on';
                    app.panel_5.Visible ='off';
                else
                    app.panel_7.Visible ='off';
                    app.panel_6.Visible ='off';
                    app.panel_5.Visible ='on';
                end
            end
            if strcmp(app.SystemorderDropDown.Value, '1st')
                app.panel_8.Visible ='on';
                app.panel_4.Visible ='off';
                app.T2EditField.Visible ='off';
                app.T2EditFieldLabel.Visible ='off';
            else
                app.panel_8.Visible ='off';
                app.panel_4.Visible ='on';
                app.T2EditField.Visible ='on';
                app.T2EditFieldLabel.Visible ='on';
            end
        end
        
        function update_figure(app)
            app.update_system()
            [K, T1, T2] = get_system_data(app);
            [Kdes, Tdes, Ddes] = get_desired(app);
            
            % Cas systeme du premier ordre
            if strcmp(app.SystemorderDropDown.Value, '1st')
                
                % Cas controller proportionnel
                if strcmp(app.ControllerDropDown.Value, 'P')
                    
                    % Cas en fonction des criteres
                    if app.CheckBox.Value
                        % Temps de reponse
                       C = (3*T1-Tdes)/(K*Tdes);
                    elseif app.CheckBox_2.Value
                        % Gain statique
                        if Kdes~=1 && Kdes~=0
                            C = -Kdes/(K*(Kdes-1));
                        elseif Kdes==1
                            Kdes = 0.9999;
                            C = -Kdes/(K*(Kdes-1));
                        elseif Kdes==0
                            Kdes = eps;
                            C = -Kdes/(K*(Kdes-1));
                        end
                    
                    end
                    Ti = 0;
                    Td = 0;
                end
                
                % Cas controller proportionnel integrale
                if strcmp(app.ControllerDropDown.Value, 'PI')
                    
                    % Cas en fonction des criteres
                    if app.OvershootEditField.Value == 0
                        % Temps de reponse
                        Ti = T1;
                        C = 3*T1/(K*Tdes);
                    else
                        syms x;
                        xi = double(solve(Ddes==exp(-x*pi/sqrt(1-x^2))*100, x, 'Real', true));
                        wn = 3/(xi*Tdes);
                        C = (2*xi*wn*T1-1)/K;
                        Ti = K*C/(wn^2*T1);
                    end
                    Td = 0;
                end
                if strcmp(app.ControllerDropDown.Value, 'PID')
                    Ti = 1;
                    Td = 1;
                    C = 1;
                end
            end
            if strcmp(app.SystemorderDropDown.Value, '2nd')
                    Ti = 1;
                    Td = 1;
                    C = 1;
            end
            % Met a jour l'interface et la variable controller
            app.update_controller_field(C, Ti, Td)
            app.update_controller()
            
            % Calcul de la boucle fermee
            closed_loop = feedback(app.system.sys*app.controller.ctr, 1);
            [y, t] = step(closed_loop);
            plot(app.UIAxes, t, y(:,:,1));
            
            all_info = stepinfo(closed_loop,'SettleTimeThreshold', 0.05);
            
            app.UIAxes.Title.String = 'Closed Loop Step Response';

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_ui(app);
        end

        % Value changed function: ControllerDropDown
        function ControllerDropDownValueChanged(app, event)
            update_ui(app);
            update_figure(app);
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            update_figure(app);            
        end

        % Value changed function: StaticgainEditField
        function StaticgainEditFieldValueChanged(app, event)
            update_figure(app);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            app.CheckBox_2.Value = ~app.CheckBox.Value;
            update_figure(app);            
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            app.CheckBox.Value = ~app.CheckBox_2.Value;
            update_figure(app);            
        end

        % Value changed function: OvershootEditField
        function OvershootEditFieldValueChanged(app, event)
            if app.OvershootEditField.Value >=100
               app.OvershootEditField.Value = 99;
            end
            if app.OvershootEditField.Value < 0
               app.OvershootEditField.Value = 0;
            end
            update_figure(app);
        end

        % Value changed function: SystemorderDropDown
        function SystemorderDropDownValueChanged(app, event)
            update_ui(app);
            update_figure(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Closed Loop Step Response')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, '')
            app.uipanel2 = uipanel(app.UIFigure);
            app.uipanel2.Title = 'System';
            app.SystemorderDropDownLabel = uilabel(app.uipanel2);
            app.SystemorderDropDownLabel.Text = 'System order';
            app.SystemorderDropDown = uidropdown(app.uipanel2);
            app.SystemorderDropDown.Items = {'1st', '2nd'};
            app.SystemorderDropDown.ValueChangedFcn = createCallbackFcn(app, @SystemorderDropDownValueChanged, true);
            app.SystemorderDropDown.Value = '1st';
            app.ParametersPanel_2 = uipanel(app.uipanel2);
            app.ParametersPanel_2.Title = 'Parameters';
            app.KEditField_2Label = uilabel(app.ParametersPanel_2);
            app.KEditField_2Label.Text = 'K';
            app.KEditField = uieditfield(app.ParametersPanel_2, 'numeric');
            app.KEditField.Editable = 'off';
            app.KEditField.Value = 1;
            app.T1EditFieldLabel = uilabel(app.ParametersPanel_2);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.ParametersPanel_2, 'numeric');
            app.T1EditField.Editable = 'off';
            app.T1EditField.Value = 1;
            app.T2EditFieldLabel = uilabel(app.ParametersPanel_2);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.ParametersPanel_2, 'numeric');
            app.T2EditField.Editable = 'off';
            app.panel_4 = uipanel(app.uipanel2);
            app.panel_4.Title = 'Transfert Function';
            app.text_k_10 = uilabel(app.panel_4);
            app.text_k_10.Text = 'K';
            app.text_k_11 = uilabel(app.panel_4);
            app.text_k_11.Text = 'G(p) = ';
            app.uipanel4_4 = uipanel(app.panel_4);
            app.uipanel5_4 = uipanel(app.panel_4);
            app.text_k_12 = uilabel(app.panel_4);
            app.text_k_12.Text = '(1 + T1 · p) (1 + T2 · p)';
            app.panel_8 = uipanel(app.uipanel2);
            app.panel_8.Title = 'Transfert Function';
            app.text_k_20 = uilabel(app.panel_8);
            app.text_k_20.Text = 'K';
            app.text_k_21 = uilabel(app.panel_8);
            app.text_k_21.Text = 'G(p) = ';
            app.uipanel4_7 = uipanel(app.panel_8);
            app.uipanel5_7 = uipanel(app.panel_8);
            app.text_k_22 = uilabel(app.panel_8);
            app.text_k_22.Text = '(1 + T1 · p)';
            app.ControllerPanel = uipanel(app.UIFigure);
            app.ControllerPanel.Title = 'Controller';
            app.ControllerDropDownLabel = uilabel(app.ControllerPanel);
            app.ControllerDropDownLabel.Text = 'Controller';
            app.ControllerDropDown = uidropdown(app.ControllerPanel);
            app.ControllerDropDown.Items = {'P', 'PI', 'PID'};
            app.ControllerDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllerDropDownValueChanged, true);
            app.ControllerDropDown.Value = 'P';
            app.ParametersPanel = uipanel(app.ControllerPanel);
            app.ParametersPanel.Title = 'Parameters';
            app.CEditFieldLabel = uilabel(app.ParametersPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.CEditField.Editable = 'off';
            app.TiEditFieldLabel = uilabel(app.ParametersPanel);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.TiEditField.Editable = 'off';
            app.TdEditFieldLabel = uilabel(app.ParametersPanel);
            app.TdEditFieldLabel.Text = 'Td';
            app.TdEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.TdEditField.Editable = 'off';
            app.panel_5 = uipanel(app.ControllerPanel);
            app.panel_5.Title = 'Transfert Function';
            app.uipanel4_5 = uipanel(app.panel_5);
            app.text_k_13 = uilabel(app.panel_5);
            app.text_k_13.Text = 'C(p) = C ';
            app.uipanel5_5 = uipanel(app.panel_5);
            app.text_k_15 = uilabel(app.uipanel5_5);
            app.text_k_15.Text = 'Ti · p';
            app.text_k_14 = uilabel(app.panel_5);
            app.text_k_14.Text = '(1 + Ti · p) (1 + Td · p)';
            app.panel_7 = uipanel(app.ControllerPanel);
            app.panel_7.Title = 'Transfert Function';
            app.text_k_19 = uilabel(app.panel_7);
            app.text_k_19.Text = 'C(p) = C ';
            app.panel_6 = uipanel(app.ControllerPanel);
            app.panel_6.Title = 'Transfert Function';
            app.uipanel4_6 = uipanel(app.panel_6);
            app.text_k_16 = uilabel(app.panel_6);
            app.text_k_16.Text = 'C(p) = C ';
            app.uipanel5_6 = uipanel(app.panel_6);
            app.text_k_18 = uilabel(app.uipanel5_6);
            app.text_k_18.Text = 'Ti · p';
            app.text_k_17 = uilabel(app.panel_6);
            app.text_k_17.Text = '(1 + Ti · p) ';
            app.DesiredPerformancesPanel = uipanel(app.UIFigure);
            app.DesiredPerformancesPanel.Title = 'Desired Performances';
            app.TimecriteriaDropDownLabel = uilabel(app.DesiredPerformancesPanel);
            app.TimecriteriaDropDownLabel.Text = 'Time criteria';
            app.TimecriteriaDropDown = uidropdown(app.DesiredPerformancesPanel);
            app.TimecriteriaDropDown.Items = {'Settling time', 'Rise time'};
            app.TimecriteriaDropDown.Value = 'Settling time';
            app.StaticgainEditFieldLabel = uilabel(app.DesiredPerformancesPanel);
            app.StaticgainEditFieldLabel.Text = 'Static gain';
            app.StaticgainEditField = uieditfield(app.DesiredPerformancesPanel, 'numeric');
            app.StaticgainEditField.ValueChangedFcn = createCallbackFcn(app, @StaticgainEditFieldValueChanged, true);
            app.OvershootEditFieldLabel = uilabel(app.DesiredPerformancesPanel);
            app.OvershootEditFieldLabel.Text = 'Overshoot';
            app.OvershootEditField = uieditfield(app.DesiredPerformancesPanel, 'numeric');
            app.OvershootEditField.ValueChangedFcn = createCallbackFcn(app, @OvershootEditFieldValueChanged, true);
            app.sEditFieldLabel = uilabel(app.DesiredPerformancesPanel);
            app.sEditFieldLabel.Text = '(s)';
            app.TimeEditField = uieditfield(app.DesiredPerformancesPanel, 'numeric');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.TimeEditField.Value = 1;
            app.CheckBox = uicheckbox(app.DesiredPerformancesPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.DesiredPerformancesPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.ObtainedPerformancesPanel = uipanel(app.UIFigure);
            app.ObtainedPerformancesPanel.Title = 'Obtained Performances';
            app.text25 = uilabel(app.UIFigure);
            app.text25.Text = 'PID : Pole placement';
            app.text26 = uilabel(app.UIFigure);
            app.text26.Text = ' © Carvalho Esteban | Pedagogical use';
            app.NotfinishedworkinprogressLabel = uilabel(app.UIFigure);
            app.NotfinishedworkinprogressLabel.Text = 'Not finished, work in progress';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pole_placement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end